classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_13      matlab.ui.control.Button
        HOGButton      matlab.ui.control.Button
        LBPButton      matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        sobelButton    matlab.ui.control.Button
        prewittButton  matlab.ui.control.Button
        robertButton   matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        Button_10      matlab.ui.control.Button
        Spinner        matlab.ui.control.Spinner
        Label_2        matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_9       matlab.ui.control.ToggleButton
        Button_8       matlab.ui.control.ToggleButton
        Button_7       matlab.ui.control.ToggleButton
        Button_6       matlab.ui.control.ToggleButton
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image % Description
        grayImage
    end
    
    methods (Access = private)
        
        function dispHistogram(app)
            % 计算灰度直方图数据
            histogramCounts = imhist(app.grayImage);
            
            % 在UIAxes上绘制直方图
            bar(app.UIAxes_2, 0:255, histogramCounts, 'BarWidth', 5, 'FaceColor', 'b');
            xlim(app.UIAxes_2, [0 255]);  % 设置x轴范围
            ylim(app.UIAxes_2, [0 max(histogramCounts)]);  % 设置y轴范围
            title(app.UIAxes_2, '灰度直方图');
            xlabel(app.UIAxes_2, '灰度值');
            ylabel(app.UIAxes_2, '像素数量');
            grid(app.UIAxes_2, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.bmp', '图像文件'});
            if isequal(file, 0)
                return;
            end
            app.Image = imread(fullfile(path, file));
            app.grayImage = customGrayScale(app.Image);
            imshow(app.Image, 'Parent', app.UIAxes);
            
            imshow(app.grayImage, 'Parent', app.UIAxes2);
            
            dispHistogram(app);
        end

        % Button pushed function: Button_3
        function Button_2Pushed2(app, event)
            equalizedImage = histogramEqualization(app.grayImage);
            specifiedImage = histogramSpecification(app.grayImage, equalizedImage);
            imshow(specifiedImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: Button_4
        function Button_3Pushed(app, event)
            enhancedImage = enhanceContrast(app.grayImage, 'linear');
            imshow(enhancedImage, 'Parent', app.UIAxes_3);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            resizedImage = bilinearResize(app.Image, value);
            resizedImage = rotateImageBilinear(resizedImage, app.Slider.Value);
            imshow(resizedImage, 'Parent', app.UIAxes);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            rotatedImage = rotateImageBilinear(app.Image, value);
            rotatedImage = bilinearResize(rotatedImage, app.Spinner.Value);
            imshow(rotatedImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: Button_2
        function Button_4Pushed(app, event)
            enhancedImage = enhanceContrast(app.grayImage, 'exponential');
            imshow(enhancedImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            enhancedImage = enhanceContrast(app.grayImage, 'log');
            imshow(enhancedImage, 'Parent', app.UIAxes_3);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '无噪声'
                    noisedImage = app.grayImage;
                case'高斯噪声'
                    noisedImage = imnoise(app.grayImage, 'gaussian', 0, 0.01);
                case'泊松噪声'
                    noisedImage = imnoise(app.grayImage, 'poisson');
                case'椒盐噪声'
                    noisedImage = imnoise(app.grayImage, 'salt & pepper', 0.05);
            end
            imshow(noisedImage, 'Parent', app.UIAxes_3);
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            noisedImage = getimage(app.UIAxes_3);
            if isempty(noisedImage)
                noisedImage = app.grayImage;
            end
            kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1]; % 锐化滤波器
            filteredImage = customSpatialFilter(noisedImage, kernel);
            imshow(filteredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            noisedImage = getimage(app.UIAxes_3);
            if isempty(noisedImage)
                noisedImage = app.grayImage;
            end
            [m, n] = size(noisedImage);
            [x, y] = meshgrid(-n/2:n/2-1, -m/2:m/2-1);
            D = sqrt(x.^2 + y.^2);
            D0 = 50; % 截止频率
            lowPassFilter = double(D <= D0); % 自定义低通滤波器
            filteredImage = customFrequencyFilter(noisedImage, lowPassFilter);
            imshow(filteredImage, 'Parent', app.UIAxes_3);
        end

        % Button pushed function: robertButton
        function robertButtonPushed(app, event)
            % Roberts算子（水平和垂直方向）
            Gx = [1, 0; 0, -1];  % 水平方向
            Gy = [0, 1; -1, 0];  % 垂直方向
            
            % 对图像进行卷积
            Ix = imfilter(double(app.Image), Gx, 'replicate');
            Iy = imfilter(double(app.Image), Gy, 'replicate');
            
            % 计算梯度幅值
            edge_image = sqrt(Ix.^2 + Iy.^2);
            
            % 显示结果
            imshow(edge_image, 'Parent',app.UIAxes_3);
        end

        % Button pushed function: prewittButton
        function prewittButtonPushed(app, event)
             % Prewitt算子（水平和垂直方向）
            Gx = [-1, 0, 1; -1, 0, 1; -1, 0, 1];  % 水平方向
            Gy = [-1, -1, -1; 0, 0, 0; 1, 1, 1];   % 垂直方向
            
            % 对图像进行卷积
            Ix = imfilter(double(app.Image), Gx, 'replicate');
            Iy = imfilter(double(app.Image), Gy, 'replicate');
            
            % 计算梯度幅值
            edge_image = sqrt(Ix.^2 + Iy.^2);
            
            % 显示结果
            imshow(edge_image, 'Parent',app.UIAxes_3);
        end

        % Button pushed function: sobelButton
        function sobelButtonPushed(app, event)
            % Sobel算子（水平和垂直方向）
            Gx = [-1, 0, 1; -2, 0, 2; -1, 0, 1];  % 水平方向
            Gy = [-1, -2, -1; 0, 0, 0; 1, 2, 1];   % 垂直方向
            
            % 对图像进行卷积
            Ix = imfilter(double(app.Image), Gx, 'replicate');
            Iy = imfilter(double(app.Image), Gy, 'replicate');
            
            % 计算梯度幅值
            edge_image = sqrt(Ix.^2 + Iy.^2);
            
            % 显示结果
            imshow(edge_image, 'Parent',app.UIAxes_3);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            h = fspecial('laplacian', 0.2);  % 创建拉普拉斯算子
    
            % 对图像进行卷积
            edge_image = imfilter(double(app.Image), h, 'replicate');
            % 显示结果
            imshow(edge_image, 'Parent',app.UIAxes_3);
        end

        % Button pushed function: LBPButton
        function LBPButtonPushed(app, event)
            lineImage = getimage(app.UIAxes_3);

            lbpOriginal = extractLBPFeatures(uint8(app.grayImage));
            lbpLine = extractLBPFeatures(lineImage);
           
            disp('LBP 特征大小:');
            disp(['原始图像: ', num2str(length(lbpOriginal))]);
            disp(['直线图像: ', num2str(length(lbpLine))]);
        end

        % Button pushed function: HOGButton
        function HOGButtonPushed(app, event)
            lineImage = getimage(app.UIAxes_3);

            hogOriginal = extractHOGFeatures(uint8(app.grayImage));
            hogLine = extractHOGFeatures(lineImage);

            disp('HOG 特征大小:');
            disp(['原始图像: ', num2str(length(hogOriginal))]);
            disp(['直线图像: ', num2str(length(hogLine))]);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            extract_features_from_image(app.grayImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '灰度图')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '处理后图像')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '灰度直方图')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0.2 0.4 0.6 0.8 1];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '加载图像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_2.Text = '灰度均衡化与匹配';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_3.Text = '线性对比度增强';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_4.Text = '对数对比度增强';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '指数对比度增强';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '添加噪声';
            app.Button_6 = uitogglebutton(app.ButtonGroup);
            app.Button_6.Text = '无噪声';
            app.Button_6.Value = true;
            app.Button_7 = uitogglebutton(app.ButtonGroup);
            app.Button_7.Text = '高斯噪声';
            app.Button_8 = uitogglebutton(app.ButtonGroup);
            app.Button_8.Text = '泊松噪声';
            app.Button_9 = uitogglebutton(app.ButtonGroup);
            app.Button_9.Text = '椒盐噪声';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 360];
            app.Slider.MajorTicks = [0 90 180 270 360];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '缩放倍数';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [0 5];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '空域滤波';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '频域滤波';
            app.robertButton = uibutton(app.UIFigure, 'push');
            app.robertButton.ButtonPushedFcn = createCallbackFcn(app, @robertButtonPushed, true);
            app.robertButton.Text = 'robert边缘提取';
            app.prewittButton = uibutton(app.UIFigure, 'push');
            app.prewittButton.ButtonPushedFcn = createCallbackFcn(app, @prewittButtonPushed, true);
            app.prewittButton.Text = 'prewitt边缘提取';
            app.sobelButton = uibutton(app.UIFigure, 'push');
            app.sobelButton.ButtonPushedFcn = createCallbackFcn(app, @sobelButtonPushed, true);
            app.sobelButton.Text = 'sobel边缘提取';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '拉普拉斯特征提取';
            app.LBPButton = uibutton(app.UIFigure, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBPButtonPushed, true);
            app.LBPButton.Text = 'LBP';
            app.HOGButton = uibutton(app.UIFigure, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOGButtonPushed, true);
            app.HOGButton.Text = 'HOG';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '提取直线';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end